classdef ResultsPathway_v1_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        UITable                matlab.ui.control.Table
        PlotResultsButton      matlab.ui.control.Button
        PathwayDropDownLabel   matlab.ui.control.Label
        PathwayDropDown        matlab.ui.control.DropDown
        Image                  matlab.ui.control.Image
        GATPListBoxLabel       matlab.ui.control.Label
        DGATPListBox           matlab.ui.control.ListBox
        RatioHATPLabel         matlab.ui.control.Label
        RatioHATPListBox       matlab.ui.control.ListBox
        pHintracellLabel       matlab.ui.control.Label
        pHinListBox            matlab.ui.control.ListBox
        GenerateTableButton    matlab.ui.control.Button
        CoAmMListBox_2Label    matlab.ui.control.Label
        CoAListBox             matlab.ui.control.ListBox
        PH2PaLabel             matlab.ui.control.Label
        PH2ListBox             matlab.ui.control.ListBox
        CO2mMListBox_2Label    matlab.ui.control.Label
        CO2ListBox             matlab.ui.control.ListBox
        pHextracellLabel       matlab.ui.control.Label
        pHoutListBox           matlab.ui.control.ListBox
        ToCListBox_2Label      matlab.ui.control.Label
        TemperatureListBox     matlab.ui.control.ListBox
        LoadResultsFileButton  matlab.ui.control.Button
    end

    % NEED TO COMMENT THIS AS SOON AS POSSIBLE (14/11/19)
    
    properties (Access = private)
        
        %INITIALIZE ALL THE VARIABLES THAT ARE GOING TO BE USED
        
        %Collect inputs and outputs for the results
        DataInputs    = [];         DataConcV     = [];
        plotConcM     = [];         plotRatios    = [];
        plotStNames   = [];         reacNames     = [];
        protTranslocV = [];         plotTransloc_eC = [];  %proton translocation for pathway and electon carrier
        DGrV        = [];           DGrV_eC     = [];
        
        %Parameters used in the plots and or calculations
        minConc     = -6;           maxConc     = -2;
        netATPV     = [];           ratio_H_ATP = [];
        posIndices  = [];           eCarrierRatioLabel = [];
        reacReoxAbbrevNames = [];   reacPlotAbbrevNames = [];
        IDRes = 1;   %Identifier of the reaction list
        Data = [];                  FigureInitPos = [];
        reacPathPlot = [];          ATP_SLP_V = [];
        checkBox = [];              pathFigure = [];
        IDFiltRes = [];             loadResultsButton = [];
        
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Define original dimensions of plot and elements
        % (Dimensions need to be defined initially from the Design View Values)
        initFigPos        = [  0     0  1815  1120];   %Figure size
        
        % Pathway selection and image
        initDropdownPos   = [136   1070  191    22];        %Position of the dropdown menu
        initPathwayDropDownLabelPos = [60  1070  67  22];   %Position of the label for the dropdown menu
        initImagePos      = [ 48    859  290   198];        %Position of the image for the pathway
        
        % Table of selection of inputs
        initDGLabelPos    = [400   1066   75   22];    %Label of the first variable (DG)
        initDGSelPos      = [400    859   75  170];    %List of values fsor the first variable (DG)
        
        % Table to select inputs
        initLoadResButton = [1065  1075  140   26];    %Button to load file results
        initGenTablePos   = [1340  1075  125   26];    %Generate Table Button Position
        initPlotResButton = [1600  1075  122   26];    %Plot Results Button Position
        initTablePos      = [1015   868  778  198];    %Position of the inputs table
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    end
    
    methods (Access = private)
        
        %*************************************************************************************************************************
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Function to plot all data in 6 subplots %
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function plotPathwayBars(app)
            
            %%%%%% PLOT DEFINITIONS %%%%%%%%%
            FontSize = 14;                  FontLegend = 11;
            LabelRotation = 30;
            deltaProt = 1;                      deltaDG = 5;  %# num of proton translocated per grid
            minProtTransloc = -3;               maxProtTransloc = 3;
            nProt = minProtTransloc:maxProtTransloc;     %Range of protons translocated to plot
            minRatio = -4;                      maxRatio = 4;
            reacNameLabels = 0;   %0 for abbreviation (R1, R2, R3.... etc), 1 for fullname
            reacLabelTicks = 0;   %1 to add ticks for reaction labels or 0 for not addingz them
            
            %Define colors of bars and markers
            colorCode = 1;   % 0 for black and white, 1 for colored
            markerList    = {'o',  's', '^',  'd', '*',  'x', 'p',  'h','<',  '>'};
            lineStyleList = {'-', '--', ':', '-.', '-', '--', ':', '-.','-', '--'};
            
            %Define bar colors
            if colorCode == 0
                posColor    = [1  1  1];    %Color for positive values
                negColor    = [1  1  1]; %Color for negative values
                ATPColor    = [1  1  1];
                DGdissColor = [1 1 1];
                outerLimColor = [0.2  0.2  0.2 0.1];
                barTransparency = 0.5;
            else
                %     posColor = [0     0.5772  0];    %Color for positive values
                %     negColor = [0.64  0.08    0.18]; %Color for negative values
                posColor        = [0.3922    0.8235    0.5294];    %Color for positive values
                negColor        = [1.0000    0.7059    0.7059]; %Color for negative values
                ATPColor        = [0.1176    0.4000    0.2118];
                outerLimColor   = [0.64  0.08  0.18 0.1];
                DGdissColor     = [0.93 0.69 0.13];
                barTransparency = 0.4;
                barWidth = 0.5;
            end
            
            %% PLOT DIMENSIONS: Main subplots in normalized units(Pathway and e-Carriers)
            left_pos     = 0.10;
            delta_vertical = 0.00;
            delta_axis = 0.35;
            bottom_posDG = 0.07; %Position of the DGPlot
            width_1 = 0.65;      %Width of Pathway Plot
            width_2 = 0.15;      %Width of e-Carriers Plot
            height = 0.3;       %Height main subplots
            height_HTransloc = 0.25;      %Height DG Plot
            height_DG = 0.08;      %Height DG Plot
            plot_space = 0.005;   %Separation between plots
            bottom_protTransloc = bottom_posDG + height_DG + delta_vertical;
            bottom_pos   = bottom_posDG + height_DG + height_HTransloc + delta_vertical*2; %Bottom position of the main plots
            LabelRotation = 30;
            
            %Plot subplots
            app.UIFigure.AutoResizeChildren = 'off';
            numRes = length(app.IDRes);
            
            %Define all subplots first
            axConc             = subplot(3,2,1,'Parent',app.UIFigure);   % Concentrations of metabolites
            ax_eC              = subplot(3,2,2,'Parent',app.UIFigure);   % Ratios of electron carriers
            ax_protTransloc    = subplot(3,2,3,'Parent',app.UIFigure);   % Protons translocated for each pathway reaction
            ax_protTransloc_eC = subplot(3,2,4,'Parent',app.UIFigure);   % Protons translocated for each electron carrier regeneration reaction
            ax_DGdiss          = subplot(3,2,5,'Parent',app.UIFigure);   % Gibbs Free energy dissipated for each pathway reaction
            ax_DGdiss_eC       = subplot(3,2,6,'Parent',app.UIFigure);   % Gibbs Free energy dissipated for each electron carrier regeneration reaction
            
            %Set proper positions for all figures and axis
            axConc.Position             = [left_pos, bottom_pos, width_1, height];
            ax_eC.Position              = [left_pos + width_1 + plot_space, bottom_pos, width_2, height];
            ax_protTransloc.Position    = [left_pos, bottom_protTransloc, width_1, height_HTransloc];
            ax_protTransloc_eC.Position = [left_pos + width_1 + plot_space, bottom_protTransloc, width_2, height_HTransloc];
            ax_DGdiss.Position          = [left_pos bottom_posDG, width_1, height_DG];
            ax_DGdiss_eC.Position       = [left_pos + width_1 + plot_space, bottom_posDG,  width_2, height_DG];
            
            
            %***************************************************************
            %***************************************************************
            %*           DEFINITION OF EACH SUBPLOT STARTS HERE            *
            %***************************************************************
            %***************************************************************
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%% 1. Concentrations of metabolites %%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %Plot concentrations in first subplot handle
            concPlot = plot(axConc, 1:length(app.DataConcV), app.DataConcV);
            
            %Label axis of the subplot1 handle
            %             xlabel(axConc, 'Metabolite')
            ylabel(axConc, 'log_{10} C_{Metabolite}')
            
            %Collect position of the axis handle to draw a second axis on
            %top of it
            axConc.Position = [left_pos, bottom_pos, width_1, height];
            
            %Set marker colours and sizes for each result
            for i = 1:numRes
                concPlot(i).MarkerSize = 10;
                concPlot(i).Marker = markerList{i};
                concPlot(i).LineStyle = lineStyleList{i};
                concPlot(i).MarkerFaceColor = [0.8 0.8 0.8];
                concPlot(i).MarkerEdgeColor = [0  0  0];
                concPlot(i).LineWidth = 1;
                concPlot(i).Color = [0 0 0];
            end
            
            %Set properties in the plot (X and Y axis limits, length of the ticks, labels and grid
            axConc.YLim = [app.minConc-delta_axis app.maxConc+delta_axis];
            axConc.XLim = [delta_axis length(app.plotStNames) + delta_axis];
            set(axConc, 'XTick', 1:length(app.plotStNames), 'XTickLabel', app.plotStNames, 'XTickLabelRotation', LabelRotation, 'XAxisLocation', 'top')
            axConc.Box = 'on';
            axConc.YGrid = 'on';
            axConc.XGrid = 'on';
            axConc.YTick = app.minConc:1:app.maxConc;
            axConc.GridLineStyle = '-';
            axConc.FontSize = FontSize;
            
            
            % Draws rectangle for unfeasible concentration limits (above max and below min)
            % e.g.: rectangle (handle, 'position',[xmin ymin xmin xmax])
            concOutLim1 = rectangle(axConc, 'position',[0 -8 length(app.plotStNames)+1 2]);
            set(concOutLim1, 'LineStyle', ':', 'LineWidth', 1, 'FaceColor', outerLimColor)
            
            concOutLim2 = rectangle(axConc, 'position',[0 -2 length(app.plotStNames)+1 2]);
            set(concOutLim2, 'LineStyle', ':', 'LineWidth', 1, 'FaceColor', outerLimColor)
            
            %Find the variable studied to be represented in the legend
            refVals = app.Data.Param.refComb;
            uniqueDataInputs = unique(app.DataInputs, 'rows');  %Only variables that are not repeated
            checkLegendCol = uniqueDataInputs - refVals;
            idVarCol = find(sum(checkLegendCol  ~= 0) > 0); %Check values that are different than 0
            
            varNames = {'\DeltaG_{ATP}'	'Ratio H/ATP'	'CoA-SH'  'pH_{in}'  'P_{H2}'   'CO_2'	 'T'	'pH_{out}'} ;
            varUnits = {'kJ/mol'	''	  'mM'	  ' '	     'Pa'	'mM'	 '^oC'	' '} ;
            varLegend = varNames(idVarCol);
            selectedResults = app.IDRes;
            
            %Define legend (FOR LATER #MPG: 24/9/19)
            %Create legend labels
            if ~isempty(app.UITable.Data) && ~isempty(idVarCol)
                rnamesLegend = cell(1, length(selectedResults));
                for i = 1:length(selectedResults)
                    rnamesLegend1  = sprintf('%s = %s %s', char(varLegend), app.UITable.Data{app.IDRes(i), idVarCol + 1}, char(varUnits(idVarCol)));
                    rnamesLegend2  = sprintf('Net ATP = %s', cell2mat(app.UITable.Data(app.IDRes(i), end)));
                    %
                    rnamesLegend{i} = sprintf('%s\n%s', char(rnamesLegend1), char(rnamesLegend2));
                end
                %Add legend if UITable is filled
                legend(axConc, rnamesLegend, 'Orientation', 'horizontal', 'Location','best', 'box', 'off', 'color','none', 'FontSize', FontLegend);
                axConc.Legend.Position = [0.16 0.43 0.273 0.045];
            end
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%% 2. Electron Carrier Ratios %%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %Set Handle for eCarriers Left Y-Axis (Protons) and Top X-Axis
            delta_Xaxis = 0.5;
            delta_Yaxis = 0;
            ax_eC.XLim = [delta_Xaxis length(app.plotRatios(1,:)) + delta_Xaxis];
            %             ax_eC.YLim = [nProt(1)-delta_Yaxis nProt(end)+delta_Yaxis];
            
            set(ax_eC, 'XTick', 1:length(app.plotRatios), 'XTickLabel', app.eCarrierRatioLabel, 'YTickLabel', '', 'XAxisLocation', 'top')
            ax_eC.Box = 'off';      %sets off box to allow axis two to be used
            ax_eC.XTickLabelRotation = LabelRotation;
            
            %Draw next axis
            ax_eC_pos = ax_eC.Position;
            ax_eC_2   = axes('Parent',app.UIFigure);
            
            %Plot Ratios
            ratioLength = length(app.plotRatios(1,:));
            %To avoid plots being transposed when plotting a squared matrix
            %(confuses MATLAB and plots in the counterdirection)
            if ratioLength == length(app.plotRatios(1,:))
                ratioPlot = plot(ax_eC_2, 1:ratioLength, app.plotRatios');
            else
                ratioPlot = plot(ax_eC_2, 1:ratioLength, app.plotRatios);
            end
            
            set(ax_eC_2,'Position', ax_eC_pos, 'XAxisLocation', 'bottom', 'YAxisLocation', 'right', 'Color', 'none');
            
            %Axis for the ratios plot
            ax_eC_2.YLim  = [-4-1 4+1];  %Ratios limits
            ax_eC_2.YLim    = ax_eC_2.YLim;
            ax_eC_2.XLim  = ax_eC.XLim;
            ax_eC_2.YGrid = 'on';
            ax_eC_2.XGrid = 'on';
            ax_eC_2.YTick = minRatio:deltaProt*2:maxRatio;
            ax_eC_2.YTickLabel = minRatio:deltaProt*2:maxRatio;
            ax_eC_2.FontSize = FontSize;
            ax_eC.FontSize = FontSize;
            ax_eC.YTick = ax_eC_2.YTick;
            
            set(ax_eC_2, 'XTick', 1:length(app.plotRatios), 'XTickLabel', '')
            ylabel(ax_eC_2, 'log_{10}(Ratio_{e^- Carriers})')
            
            
            %Set Marker Colours and Sizes
            for i = 1:numRes
                ratioPlot(i).MarkerSize = 10;
                ratioPlot(i).Marker = markerList{i};
                ratioPlot(i).MarkerFaceColor = [0.8 0.8 0.8];
                ratioPlot(i).MarkerEdgeColor = [0   0   0];
                ratioPlot(i).LineStyle = 'none';
                ratioPlot(i).Color = [0 0 0];
            end
            
            % New rectangle outside of feasible Ratios eCarriers
            concOutLim1 = rectangle(ax_eC_2, 'position',[0 -7 length(app.plotRatios)+1 3]);
            set(concOutLim1, 'LineStyle', ':', 'LineWidth', 1, 'FaceColor', outerLimColor)
            
            concOutLim2 = rectangle(ax_eC_2, 'position',[0 4 length(app.plotRatios)+1 3]);
            set(concOutLim2, 'LineStyle', ':', 'LineWidth', 1, 'FaceColor', outerLimColor)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%% 3. Proton Translocations and ATP  %%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            app.posIndices = app.protTranslocV >= 0;
            %Plots positive energy recovery sites
            b1 = bar(ax_protTransloc, app.protTranslocV' .* (app.posIndices' == 1), 'FaceColor', posColor, 'BarWidth', barWidth);
            hold(ax_protTransloc, "on");   %Analogous to hold on
            %Plots reactions that need to be pumped in
            b2 = bar(ax_protTransloc, app.protTranslocV'.* (app.posIndices' == 0), 'FaceColor', negColor, 'BarWidth', barWidth);
            %Plots reactions that use ATP at substrate level
            b3 = bar(ax_protTransloc, app.ATP_SLP_V' .* app.ratio_H_ATP', 'FaceColor', ATPColor, 'FaceAlpha', barTransparency, 'BarWidth', barWidth);  % TEMPORARY FIX. THE VECTOR SHOULD BE MAINTAINED
            
            ylabel(ax_protTransloc,{'# of H^+';'Translocated'});
            
            %Sets characteristics of Bottom X-Axis and Right Y-Axis
            ax_protTransloc.XLim = [0 length(app.protTranslocV)+1];
            set(ax_protTransloc, 'XTick', 1:length(app.protTranslocV), 'XTickLabel', '');
            
            %Sets the Y Axis as a function of the ATP 

            ax_protTransloc.YLim  = [-ceil(max(app.ratio_H_ATP)) - delta_axis   ceil(max(app.ratio_H_ATP)) + delta_axis]; ceil(3.33)
            ax_protTransloc.YTick = -ceil(max(app.ratio_H_ATP)):deltaProt:ceil(max(app.ratio_H_ATP));

            ax_protTransloc.TickLength = [0 0];
            ax_protTransloc.FontSize = FontSize;
            
            %Draw Bottom X-axis and Right Y-Axis for pathway reactions
            axprotTransloc_pos = ax_protTransloc.Position;
            ax_protTransloc2   = axes('Parent',app.UIFigure);
            set(ax_protTransloc2, 'Position', axprotTransloc_pos, 'XAxisLocation', 'bottom', 'YAxisLocation', 'left', 'Color', 'none');
            
            ax_protTransloc2.XLim  = axConc.XLim;
            ax_protTransloc2.XTick = axConc.XTick;
            ax_protTransloc2.XTickLabel = '';
            ax_protTransloc2.YLim  = ax_protTransloc.YLim;
            ax_protTransloc2.YTick = ax_protTransloc.YTick;
            ax_protTransloc2.YTickLabel = '';
            
            ax_protTransloc2.YGrid = 'on';
            ax_protTransloc2.XGrid = 'on';
            ax_protTransloc2.GridLineStyle = '-';
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%% 4. Proton Translocations for eCarriers %%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %Plot Results
            b4 = bar(ax_protTransloc_eC, bsxfun(@times, app.plotTransloc_eC', (app.plotTransloc_eC' >= 0))', 'FaceColor', posColor, 'BarWidth', barWidth);
            hold(ax_protTransloc_eC, "on");
            b5 = bar(ax_protTransloc_eC, bsxfun(@times, app.plotTransloc_eC', (app.plotTransloc_eC' < 0))', 'FaceColor', negColor, 'BarWidth', barWidth);
            set(ax_protTransloc_eC,'YAxisLocation','right')
            
            ax_protTransloc_eC.YLim  = ax_protTransloc.YLim;
            ax_protTransloc_eC.YTick = ax_protTransloc.YTick;
            
            ax_protTransloc_eC.XTickLabel = '';
            ax_protTransloc_eC.YGrid = 'on';
            ax_protTransloc_eC.XGrid = 'on';
            ax_protTransloc_eC.GridLineStyle = '-';
            ax_protTransloc_eC.XTick = ax_eC.XTick;
            ax_protTransloc_eC.XLim  = ax_eC.XLim;
            ax_protTransloc_eC.FontSize = FontSize;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%% 5. Gibbs Free Energies %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %Plot Results
            b6 = bar(ax_DGdiss, -app.DGrV', 'FaceColor', DGdissColor, 'BarWidth', barWidth);
            
            ax_DGdiss.XTick = ax_protTransloc.XTick;
            ax_DGdiss.XLim  = ax_protTransloc.XLim;
            ax_DGdiss.XTickLabel = app.reacPlotAbbrevNames;
            
            ylabel(ax_DGdiss,{' \DeltaG_{diss} ';' (kJ/mol)'});
            xlabel(ax_DGdiss,{'Reaction #'});
            
            maxDG = max(max(-app.DGrV));
            DGrounder = roundn(maxDG,0);
            ax_DGdiss.YLim = [0 maxDG+1];
            ax_DGdiss.YTick = 0:round(DGrounder/2,1):DGrounder;
            ax_DGdiss.YGrid = 'on';
            ax_DGdiss.FontSize = FontSize;
            ax_DGdiss.TickLength = [ 0 0 ];
            
            ax_DGdiss_pos = ax_DGdiss.Position;
            ax_DGdiss2  = axes('Parent',app.UIFigure);
            set(ax_DGdiss2, 'Position', ax_DGdiss_pos, 'XAxisLocation', 'bottom', 'YAxisLocation', 'left', 'Color', 'none');
            
            ax_DGdiss2.YTick = '';
            ax_DGdiss2.XLim  = axConc.XLim;
            ax_DGdiss2.XTick = axConc.XTick;
            ax_DGdiss2.XTickLabel = '';
            
            ax_DGdiss2.YGrid = 'on';
            ax_DGdiss2.XGrid = 'on';
            ax_DGdiss2.GridLineStyle = '-';
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%% 6. Gibbs Free Energies eCarriers %%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            b7 = bar(ax_DGdiss_eC, -app.DGrV_eC', 'FaceColor', DGdissColor, 'BarWidth', barWidth);
            
            % ylabel({'\DeltaG_{diss} ';' (kJ/mol)'})
            set(ax_DGdiss_eC,  'XTick', ax_eC.XTick, 'XLim', ax_eC.XLim, 'XTickLabel', app.reacReoxAbbrevNames, ...
                'XTickLabelRotation', LabelRotation, 'YAxisLocation','right')
            ax_DGdiss_eC.YTickLabel = ax_DGdiss.YTickLabel;
            ax_DGdiss_eC.YLim = [0 max(max(-app.DGrV)) + 1];
            ax_DGdiss_eC.YTick = ax_DGdiss.YTick;
            ax_DGdiss_eC.YGrid = 'on';
            ax_DGdiss_eC.XGrid = 'on';
            ax_DGdiss_eC.FontSize = FontSize;
            
        end
        %*************************************************************************************************************************
        
        %*************************************************************************************************************************
        function strData = relabelTable(app, ResData)
            %This function prepares the values from numerical numbers to be
            %written in the table of inputs with the proper notation
            strData = cell(size(ResData));  % Preallocate matrix
            colRatio = 2 ;                  % Patch to select the column for ratio H+/ATP
            DG_H2 = 18.8;                   % Gibbs energy of formation of molecular hydrogen
            Cels2Kelv = 273.15;             %Conversion from Celsius to Kelvin
            
            %Re-structure dataLabels
            for i = 1:length(ResData(:,1))
                for j = 1:length(ResData(1,:))
                    if abs(ResData(i,j)) >= 1
                        if mod(ResData(i,j), 1) == 0 && j ~= colRatio
                            strData{i,j} = sprintf('%.0f', ResData(i,j));
                        elseif j == colRatio
                            strData{i,j} = char(rationalTickLabels(ResData(i,j),3,0));
                        else
                            strData{i,j} = sprintf('%.2f', ResData(i,j));
                        end
                    elseif ResData(i,j)<1 && ResData(i,j)>=0.1
                        strData{i,j} = sprintf('%.2f', ResData(i,j));
                    elseif ResData(i,j)<0.1 && ResData(i,j)>=0.001
                        strData{i,j} = sprintf('%.0f', ResData(i,j) * 1000);
                    elseif ResData(i,j)<0.001 && ResData(i,j)>=1e-6
                        strData{i,j} = sprintf('%.3f', ResData(i,j) * 1000);
                    elseif ResData(i,j)<1e-6 && ResData(i,j)>=0
                        P_H2 = ResData(i,j) /(exp(-DG_H2/(app.Data.Param.Rth * (Cels2Kelv +35)))) * 1e5;    %To get Hydrogen in Pa
                        strData{i,j} = sprintf('%.2f', P_H2);
                    elseif ResData(i,j)<0
                        strData{i,j} = sprintf('%.2f', ResData(i,j));
                    end
                end
            end
        end
        %*************************************************************************************************************************
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %Define default pathway to start plotting (THIS IS ONLY FOR DEVELOPING PURPOSES)
            app.reacPathPlot = 'P1a';
            app.pathFigure = char(strcat(pwd,'\pathwayLayout\',app.reacPathPlot,'.jpg'));
            
            %Write appropriate headers in table of GUI
            app.UITable.ColumnName = {'Select'; [char(916) 'G_{ATP})']; 'Ratio H+/ATP'; '[CoA] (mM)'; 'pH in'; 'P_{H2} (Pa)'; '[CO2] (mM)'; 'T (K)'; 'pH out'; 'Net ATP'};
            app.UITable.RowName = [];
            % Load the data
            app.Data = load('allPlotResultsGUI.mat');
            
            %Put the values in the center of the table
            s = uistyle('HorizontalAlignment','center');
            addStyle(app.UITable,s);
            
            %Obtain names of the metabolites
            app.plotStNames = app.Data.plotResults.(char(app.reacPathPlot)).plotStNames;
            
            % Collect concentration of metabolites of the selected results
            app.plotConcM   = app.Data.plotResults.(char(app.reacPathPlot)).plotConcM;
            
            %Add List of pathways
            % app.PathwayDropDown.Items = app.Data.Reac.Pathway;
            % -Abbreviated version
            
            %Longer version of pathways list
            app.PathwayDropDown.Items = app.Data.Reac.PathwayNames;
            
            %Add values sorted that will be used in the Table
            app.DataInputs  = app.Data.plotResults.(char(app.reacPathPlot)).InputsSorted;
            app.netATPV     = app.Data.plotResults.(char(app.reacPathPlot)).netATPSorted;
            
            %Add Initial Values
            app.DataConcV   = log10(app.plotConcM(:,app.IDRes));
            app.plotRatios  = log10(app.Data.plotResults.(char(app.reacPathPlot)).eC_Ratios_Sorted(app.IDRes,:));
            
            %Proton Translocations and ATP at substrate level
            app.protTranslocV      = app.Data.plotResults.(char(app.reacPathPlot)).plotprotTranslocReac(app.IDRes, :);
            app.ATP_SLP_V          = app.Data.plotResults.(char(app.reacPathPlot)).ATP_SLP_V;
            app.ratio_H_ATP        = app.Data.plotResults.(char(app.reacPathPlot)).InputsSorted(app.IDRes,2); % TO CORRECT
            app.plotTransloc_eC    = app.Data.plotResults.(char(app.reacPathPlot)).plotprotTransloc_eC(app.IDRes, :);
            
            %Gibbs Free Energies
            app.DGrV               = app.Data.plotResults.(char(app.reacPathPlot)).DGrM_Reac(app.IDRes, :);
            app.DGrV_eC            = app.Data.plotResults.(char(app.reacPathPlot)).DGrM_eC(app.IDRes, :);
            
            %Labels
            app.eCarrierRatioLabel = app.Data.plotResults.(char(app.reacPathPlot)).eCarrierRatioLabel;
            app.reacReoxAbbrevNames = app.Data.plotResults.(char(app.reacPathPlot)).reacReoxAbbrevNames;
            app.reacPlotAbbrevNames = app.Data.plotResults.(char(app.reacPathPlot)).reacPlotAbbrevNames;
            
            %Plot the variables
            plotPathwayBars(app)
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            %             app.IDRes = indices(1);
            %             resToPlot = [log10(plotConcV(1,:))];
        end

        % Button pushed function: PlotResultsButton
        function PlotResultsButtonPushed(app, event)
            
            %Update the results based on the selection
            checkVals = cell2mat(app.UITable.Data(:,1));
            app.IDRes = find(checkVals == 1);
            
            IDPlotRes = checkVals .* app.IDFiltRes;
            IDPlotRes(IDPlotRes == 0) = [];
            
            %Add Initial Values
            app.DataConcV   = log10(app.Data.plotResults.(char(app.reacPathPlot)).plotConcM(:,IDPlotRes));
            app.plotRatios  = log10(app.Data.plotResults.(char(app.reacPathPlot)).eC_Ratios_Sorted(IDPlotRes,:));
            
            %Protons translocated
            app.protTranslocV      = app.Data.plotResults.(char(app.reacPathPlot)).plotprotTranslocReac(IDPlotRes, :);
            app.ATP_SLP_V          = app.Data.plotResults.(char(app.reacPathPlot)).ATP_SLP_V;
            app.ratio_H_ATP        = app.Data.plotResults.(char(app.reacPathPlot)).InputsSorted(IDPlotRes,2); % TO CORRECT
            app.plotTransloc_eC    = app.Data.plotResults.(char(app.reacPathPlot)).plotprotTransloc_eC(IDPlotRes, :)';
            
            %Gibbs Free Energies
            app.DGrV    = app.Data.plotResults.(char(app.reacPathPlot)).DGrM_Reac(IDPlotRes, :);
            app.DGrV_eC = app.Data.plotResults.(char(app.reacPathPlot)).DGrM_eC(IDPlotRes, :)';
            
            %Labels
            app.eCarrierRatioLabel = app.Data.plotResults.(char(app.reacPathPlot)).eCarrierRatioLabel;
            app.reacReoxAbbrevNames = app.Data.plotResults.(char(app.reacPathPlot)).reacReoxAbbrevNames;
            app.reacPlotAbbrevNames = app.Data.plotResults.(char(app.reacPathPlot)).reacPlotAbbrevNames;
            
            %Plot the variables after updating the results
            plotPathwayBars(app);
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            %Change the position of the table and the buttons when window is maximised
            app.UIFigure.Color = [1 1 1]; % Temporary fix to make the figure white all the time it is resized
            figPosition    = app.UIFigure.Position;
            %             buttonPosition = app.PlotResultsButton.Position;
            %             dropdownPos    = app.PathwayDropDown.Position;
            
            %Calculate percentage of each one of the elements
            %Proportions of objects
            refPercFig = [app.initFigPos(3) app.initFigPos(4) app.initFigPos(3) app.initFigPos(4)];
            %Percentage of images
            percImage          = app.initImagePos      ./ refPercFig;
            percTable          = app.initTablePos      ./ refPercFig;
            percDropDown       = app.initDropdownPos   ./ refPercFig;
            percLoadResButton  = app.initLoadResButton ./ refPercFig;
            percGenTableButton = app.initGenTablePos   ./ refPercFig;
            percPlotResBut     = app.initPlotResButton ./ refPercFig;
            percDGSelPos       = app.initDGSelPos      ./ refPercFig;
            width_selector = [70 0 0 0];
            
            
            %Table resizing
            app.UITable.Position = [figPosition(3)*percTable(1), figPosition(4)*percTable(2), figPosition(3)*percTable(3), figPosition(4)*percTable(4)];   %Table
            upperCornerTable     = figPosition(4)*percTable(2) + figPosition(4)*percTable(4);
            
            app.Image.Position   = [figPosition(3)*percImage(1), upperCornerTable - figPosition(4)*percImage(4), figPosition(3)*percImage(3), figPosition(4)*percImage(4)];   %Figure
            
            
            %Update the location of the plot elements
            %           app.DGATPListBox.Position = [figPosition(3) * percDGSelPos(1), figPosition(4) * percDGSelPos(2),  width_selector(1), app.initDGSelPos(4)];
            app.DGATPListBox.Position = [figPosition(3) * percDGSelPos(1), upperCornerTable - app.initDGSelPos(4),  width_selector(1), app.initDGSelPos(4)];
            
            app.RatioHATPListBox.Position = app.DGATPListBox.Position + width_selector;
            app.CoAListBox.Position = app.RatioHATPListBox.Position + width_selector;
            app.pHinListBox.Position = app.CoAListBox.Position + width_selector;
            
            app.PH2ListBox.Position = app.pHinListBox.Position + width_selector;
            app.CO2ListBox.Position = app.PH2ListBox.Position + width_selector;
            app.TemperatureListBox.Position = app.CO2ListBox.Position + width_selector;
            app.pHoutListBox.Position = app.TemperatureListBox.Position + width_selector;
            
            %Move Labels
            app.GATPListBoxLabel.Position = [app.DGATPListBox.Position(1) figPosition(4)*percGenTableButton(2)  width_selector(1) 35];
            app.RatioHATPLabel.Position      = app.GATPListBoxLabel.Position    + width_selector;
            app.CoAmMListBox_2Label.Position = app.RatioHATPLabel.Position      + width_selector;
            app.pHintracellLabel.Position    = app.CoAmMListBox_2Label.Position + width_selector;
            app.PH2PaLabel.Position          = app.pHintracellLabel.Position    + width_selector;
            app.CO2mMListBox_2Label.Position = app.PH2PaLabel.Position          + width_selector;
            app.ToCListBox_2Label.Position   = app.CO2mMListBox_2Label.Position + width_selector;
            app.pHextracellLabel.Position    = app.ToCListBox_2Label.Position   + width_selector;
            
            
            %Buttons positions
            app.PathwayDropDown.Position      = [figPosition(3)*percDropDown(1), figPosition(4)*percDropDown(2), app.initDropdownPos(3), app.initDropdownPos(4)];
            app.PathwayDropDownLabel.Position = [app.PathwayDropDown.Position(1)- (app.initDropdownPos(1) - app.initPathwayDropDownLabelPos(1)), app.PathwayDropDown.Position(2), app.initPathwayDropDownLabelPos(3), app.initPathwayDropDownLabelPos(4)];
            app.LoadResultsFileButton.Position    = [figPosition(3)*percLoadResButton(1), figPosition(4)*percLoadResButton(2), app.initLoadResButton(3), app.initLoadResButton(4)];
            app.GenerateTableButton.Position  = [figPosition(3)*percGenTableButton(1), figPosition(4)*percGenTableButton(2), app.GenerateTableButton.Position(3), app.GenerateTableButton.Position(4)];
            app.PlotResultsButton.Position    = [figPosition(3)*percPlotResBut(1), figPosition(4)*percPlotResBut(2), app.initPlotResButton(3), app.initPlotResButton(4)];

            
            %Alternative to generalisation
            %   position = app.UITable.Position;
            %   PercentageWidth  = (position(3) - app.FigureInitPos(3))/app.FigureInitPos(3);
            %   PercentageHeight = (position(4) - app.FigureInitPos(4))/app.FigureInitPos(4);
            %   app.UITable.Position = [posTable(1) * (1+PercentageWidth), posTable(2) * (1+PercentageHeight), posTable(3) * (1+PercentageWidth), posTable(4) * (1+PercentageHeight)];
        end

        % Value changed function: PathwayDropDown
        function PathwayDropDownValueChanged(app, event)
            value = app.PathwayDropDown.Value;
            
            idPathway = strcmp(value, app.PathwayDropDown.Items);
            app.reacPathPlot = app.Data.Reac.Pathway(idPathway);
            
            %Change value to Reaction Pathway
            app.plotStNames = app.Data.plotResults.(char(app.reacPathPlot)).plotStNames;
            app.eCarrierRatioLabel = app.Data.plotResults.(char(app.reacPathPlot)).eCarrierRatioLabel;
            app.checkBox = num2cell(false(length(app.Data.plotResults.(char(app.reacPathPlot)).netATPSorted), 1));
            
            %Update Image in Thumbnail
            app.pathFigure = char(strcat(pwd,'\pathwayLayout\',app.reacPathPlot,'.jpg'));
            app.Image.ImageSource = app.pathFigure;
            
%             GenerateTableButtonPushed()
            %Add values that will be used in the Table
            %             app.DataInputs  = app.Data.plotResults.(char(app.reacPathPlot)).InputsSorted;
            %             app.netATPV     = app.Data.plotResults.(char(app.reacPathPlot)).netATPSorted;
            %             app.UITable.Data = [app.checkBox, num2cell(app.DataInputs), num2cell(app.netATPV)];
            %             strData = relabelTable(app, resData);
            %             app.UITable.Data = [app.checkBox, strData];
            
        end

        % Button pushed function: GenerateTableButton
        function GenerateTableButtonPushed(app, event)
            %Define Table to plot results
            refDG     = str2double(app.DGATPListBox.Value);        %kJ/mol
            
            refRatioH = zeros(1, length(app.RatioHATPListBox.Value));
            for j = 1:length(app.RatioHATPListBox.Value)
                refRatioH(j) = str2num(app.RatioHATPListBox.Value{j});    %Ratio H+/ATP (best alternative so far is str2num, as str2double is not applicable to rational numbers
            end
            
            refCoA    = str2double(app.CoAListBox.Value) / 1000;            %CoA-SH (in M)
            refpHin   = str2double(app.pHinListBox.Value);                  %pH_in
            refPh2    = str2double(app.PH2ListBox.Value);                   %P_H2 (bar)
            refCO2    = str2double(app.CO2ListBox.Value) / 1000;            %CO2 (M)
            refT      = str2double(app.TemperatureListBox.Value) + 273.15;  %T (K)
            refpHout  = str2double(app.pHoutListBox.Value);                 %pH_out
            
            %TEMPORARY FIX --> Concentrations of Hydrogen should be loaded
            %from parameters or any other source
            ConcH2           = [1.00E-10    1.00E-09	1.28E-08	2.50E-07    1e-6];
            idH2 = (refPh2 == str2double(app.PH2ListBox.Items));
            %Identify the concentrations of dissolved hydrogen
            refConcH2 = ConcH2(idH2);
            
            %Create a combination matrix of all the results to plot
            refParM = allcomb(refDG, refRatioH, refCoA, refpHin, refConcH2, refCO2, refT, refpHout);
            
            %Preallocate matrices to allow appendage of new results
            totalfilteredVals = [];
            idFilteredVals = [];
            pathwayInputs = app.Data.plotResults.(char(app.reacPathPlot)).InputsSorted;
            
            % Round the values of the ratios in the reference matrix and in the pathwayInputs matrix
            % to make sure all ratios are properly selected
            refParM(:,2)       = round(refParM(:,2),       4);
            pathwayInputs(:,2) = round(pathwayInputs(:,2), 4);
            
            %For each row of the matrix to check,identify the values from the results matrix
            for i = 1:length(refParM(:,1))
                
                %Find preselected values from the total inputs
                filteredValues = pathwayInputs(pathwayInputs(:,1) == refParM(i,1) & pathwayInputs(:,2) == refParM(i,2) &  ...
                    pathwayInputs(:,3) == refParM(i,3) & pathwayInputs(:,4) == refParM(i,4) & pathwayInputs(:,5) == refParM(i,5) & ...
                    pathwayInputs(:,6) == refParM(i,6) & pathwayInputs(:,7) == refParM(i,7) & pathwayInputs(:,8) == refParM(i,8), :);
                
                %Add previous results to new ones
                totalfilteredVals = [totalfilteredVals; filteredValues];
                
                %Find position of filteredValues
                previdFiltVals = find(sum(refParM(i,:) == pathwayInputs,2) == length(pathwayInputs(1,:)));
                
                %Add previous results to new ones
                idFilteredVals = [idFilteredVals; previdFiltVals];
                
            end
            
            %Identify filtered values from the selected inputs
            app.IDFiltRes = idFilteredVals;
            
            %Generate Table Values (Already sorted values are filtered
            app.DataInputs  = app.Data.plotResults.(char(app.reacPathPlot)).InputsSorted(idFilteredVals,:);
            app.netATPV     = app.Data.plotResults.(char(app.reacPathPlot)).netATPSorted(idFilteredVals,:);
            resData = [app.DataInputs, app.netATPV];
            
            %Ticks checkbox
            app.checkBox = num2cell(false(length(resData(:,1)),1));
            strData = relabelTable(app, resData);
            app.UITable.Data = [app.checkBox, strData];
            
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            winopen(app.pathFigure)
        end

        % Button pushed function: LoadResultsFileButton
        function LoadResultsFileButtonPushed(app, event)
            %Asks for a *.mat file when the load file button is pushed
%             uiopen("load");
%             app.Data = plotResults;
%             
            fileResultsName = uigetfile('*.mat', 'Select .mat File Results to Plot');
            app.Data = load(fileResultsName); %Overwrite Data with the results required
            
            app.PathwayDropDown.Items = app.Data.Reac.PathwayNames;
            app.plotStNames = app.Data.plotResults.(char(app.reacPathPlot)).plotStNames;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Select'; 'ΔG_{ATP})'; 'Ratio H^+/ATP'; '[CoA] (mM)'; 'pH_{in}'; 'P_{H2} (ppm)'; '[CO_2] (mM)'; 'T (^o C)'; 'pH_{out}'; 'Net ATP'};
            app.UITable.RowName = {''};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = [true false false false false false false false false false];
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.PlotResultsButton = uibutton(app.UIFigure, 'push');
            app.PlotResultsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotResultsButtonPushed, true);
            app.PlotResultsButton.Text = 'Plot Results';
            app.PathwayDropDownLabel = uilabel(app.UIFigure);
            app.PathwayDropDownLabel.Text = 'Pathway';
            app.PathwayDropDown = uidropdown(app.UIFigure);
            app.PathwayDropDown.ValueChangedFcn = createCallbackFcn(app, @PathwayDropDownValueChanged, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'P4a.jpg';
            app.GATPListBoxLabel = uilabel(app.UIFigure);
            app.GATPListBoxLabel.Text = 'ΔG ATP';
            app.DGATPListBox = uilistbox(app.UIFigure);
            app.DGATPListBox.Items = {'-40', '-45', '-50', '-55', '-60', '-65'};
            app.DGATPListBox.Multiselect = 'on';
            app.DGATPListBox.Value = {'-50'};
            app.RatioHATPLabel = uilabel(app.UIFigure);
            app.RatioHATPLabel.Text = {'Ratio '; 'H+/ATP'};
            app.RatioHATPListBox = uilistbox(app.UIFigure);
            app.RatioHATPListBox.Items = {'9/3', '10/3', '11/3', '12/3', '13/3', '14/3', '15/3'};
            app.RatioHATPListBox.Multiselect = 'on';
            app.RatioHATPListBox.Value = {'9/3'};
            app.pHintracellLabel = uilabel(app.UIFigure);
            app.pHintracellLabel.Text = {'pH '; 'intracell'};
            app.pHinListBox = uilistbox(app.UIFigure);
            app.pHinListBox.Items = {'6', '6.5', '7', '7.5', '8', ''};
            app.pHinListBox.Multiselect = 'on';
            app.pHinListBox.Value = {'7'};
            app.GenerateTableButton = uibutton(app.UIFigure, 'push');
            app.GenerateTableButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateTableButtonPushed, true);
            app.GenerateTableButton.Text = 'Generate Table';
            app.CoAmMListBox_2Label = uilabel(app.UIFigure);
            app.CoAmMListBox_2Label.Text = {'[CoA]'; '(mM)'};
            app.CoAListBox = uilistbox(app.UIFigure);
            app.CoAListBox.Items = {'10', '1', '0.1', '0.01', '0.001'};
            app.CoAListBox.Multiselect = 'on';
            app.CoAListBox.Value = {'1'};
            app.PH2PaLabel = uilabel(app.UIFigure);
            app.PH2PaLabel.Text = {'PH2'; '(Pa)'};
            app.PH2ListBox = uilistbox(app.UIFigure);
            app.PH2ListBox.Items = {'0.01', '0.13', '1.62', '31.63', '316.3'};
            app.PH2ListBox.Multiselect = 'on';
            app.PH2ListBox.Value = {'0.13'};
            app.CO2mMListBox_2Label = uilabel(app.UIFigure);
            app.CO2mMListBox_2Label.Text = {'[CO2]'; '(mM)'};
            app.CO2ListBox = uilistbox(app.UIFigure);
            app.CO2ListBox.Items = {'10', '1', '0.1', '0.01', '0.001'};
            app.CO2ListBox.Multiselect = 'on';
            app.CO2ListBox.Value = {'10'};
            app.pHextracellLabel = uilabel(app.UIFigure);
            app.pHextracellLabel.Text = {'pH '; 'extracell'};
            app.pHoutListBox = uilistbox(app.UIFigure);
            app.pHoutListBox.Items = {'6', '7', '8'};
            app.pHoutListBox.Multiselect = 'on';
            app.pHoutListBox.Value = {'7'};
            app.ToCListBox_2Label = uilabel(app.UIFigure);
            app.ToCListBox_2Label.Text = {'T '; '(oC)'};
            app.TemperatureListBox = uilistbox(app.UIFigure);
            app.TemperatureListBox.Items = {'25', '35', '45', '55'};
            app.TemperatureListBox.Multiselect = 'on';
            app.TemperatureListBox.Value = {'35'};
            app.LoadResultsFileButton = uibutton(app.UIFigure, 'push');
            app.LoadResultsFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadResultsFileButtonPushed, true);
            app.LoadResultsFileButton.Text = 'Load Results File';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ResultsPathway_v1_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end